classdef ballAndBeamapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimulationTab                   matlab.ui.container.Tab
        StepinformationPanel            matlab.ui.container.Panel
        overshootEditField              matlab.ui.control.NumericEditField
        overshootLabel                  matlab.ui.control.Label
        peaktimeEditField               matlab.ui.control.NumericEditField
        peaktimeEditFieldLabel          matlab.ui.control.Label
        peakEditField                   matlab.ui.control.NumericEditField
        peakEditFieldLabel              matlab.ui.control.Label
        settlingtimeEditField           matlab.ui.control.NumericEditField
        settlingtimeEditFieldLabel      matlab.ui.control.Label
        risetimeEditField               matlab.ui.control.NumericEditField
        risetimeEditFieldLabel          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        KrvalueLabel                    matlab.ui.control.Label
        GrTrLabel                       matlab.ui.control.Label
        GsTrLabel                       matlab.ui.control.Label
        KrLabel                         matlab.ui.control.Label
        GrLabel                         matlab.ui.control.Label
        GsLabel                         matlab.ui.control.Label
        StopsimulationButton            matlab.ui.control.Button
        SimulationofaballpostioncontrolLabel  matlab.ui.control.Label
        BallandbeamsystemLabel          matlab.ui.control.Label
        ResultsPanel_2                  matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        SimulateButton                  matlab.ui.control.Button
        stepresponsecharacteristicsPanel  matlab.ui.container.Panel
        maxrisingtimesEditField         matlab.ui.control.NumericEditField
        maxrisingtimesEditFieldLabel    matlab.ui.control.Label
        maxovershootEditField           matlab.ui.control.NumericEditField
        maxovershootLabel               matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        ReferencevalueEditField         matlab.ui.control.NumericEditField
        ReferencevaluemSlider           matlab.ui.control.Slider
        ReferencevaluemSliderLabel      matlab.ui.control.Label
        ballpositionmEditField          matlab.ui.control.NumericEditField
        ballpositionmEditFieldLabel     matlab.ui.control.Label
        lengthofthebeammEditField       matlab.ui.control.NumericEditField
        lengthofthebeammEditFieldLabel  matlab.ui.control.Label
        leverarmoffsetmEditField        matlab.ui.control.NumericEditField
        leverarmoffsetmEditFieldLabel   matlab.ui.control.Label
        ballradiusmEditField            matlab.ui.control.NumericEditField
        ballradiusmEditFieldLabel       matlab.ui.control.Label
        ballmasskgEditField             matlab.ui.control.NumericEditField
        ballmasskgEditFieldLabel        matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        rlocusPanel                     matlab.ui.container.Panel
        rlocus                          matlab.ui.control.UIAxes
        NyquistPanel                    matlab.ui.container.Panel
        nyquist                         matlab.ui.control.UIAxes
        BodePanel                       matlab.ui.container.Panel
        bode                            matlab.ui.control.UIAxes
        SimulationofaballpostioncontrolLabel_2  matlab.ui.control.Label
        BallandbeamsystemLabel_2        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Gr; % transfer function of a regulator
        Gs; % transfer function of a process
        Kr; % gain of a regulator
    end
    
    methods (Access = private)
        
        function calculateParameters(app,m,R,d,L,g,J,sigma,rt)

            %calculating damping based on the overshoot
            zeta=sqrt((log(sigma/100))^2/(pi^2+(log(sigma/100))^2));
            
            %calculating frequency based on the rising time (10% - 90%)
            
            omega=pi/(2*rt*sqrt(1-zeta^2));
            
            s = tf('s');
            app.Gs=-m*g*d/(L*(J/R^2 + m)*s^2); %transfer function of a process

               
            zo=0.001;
            po=omega*2;
            app.Gr=(s+zo)/(s+po); %transfer function of a regulator
            
            Go=app.Gr*app.Gs; %open loop transfer function
           

            [poles,gains]=rlocus(Go);
            %finding the index of the pole with damping > zeta 
            index = find(abs(real(poles))./sqrt((real(poles)).^2+(imag(poles)).^2) > zeta & abs(real(poles))./sqrt((real(poles)).^2+(imag(poles)).^2) < (zeta+0.1), 1);
            pole=poles(index);
            sizeOfArray=size(poles,1);

            app.Kr=gains(ceil(index/sizeOfArray)); % gain of a regulator
        end
        
        function startSimulation(app,r0,rv)
           [numGr,denGr] =tfdata(app.Gr);
           [numGs,denGs]=tfdata(app.Gs);
           numGr=cell2mat(numGr);
           denGr=cell2mat(denGr);
           numGs=cell2mat(numGs);
           denGs=cell2mat(denGs);

           %Loading model in the memory and setting the parameters
            load_system('ballAndBeamModel')

            set_param('ballAndBeamModel/Step', 'After', num2str(rv));
            set_param('ballAndBeamModel/Gain', 'Gain', num2str(app.Kr));
            set_param('ballAndBeamModel/LeadCompensator', 'Numerator', mat2str(numGr));
            set_param('ballAndBeamModel/LeadCompensator', 'Denominator', mat2str(denGr) );
            set_param('ballAndBeamModel/Process', 'Numerator',mat2str(numGs));
            set_param('ballAndBeamModel/Process', 'Denominator', mat2str(denGs) );
            set_param('ballAndBeamModel/Constant','Value', num2str(r0));

            set_param('ballAndBeamModel', 'SimulationCommand', 'start')

            %open the scope to view simulation
            open_system('ballAndBeamModel/output')

        end
        
        function calculateStepInfo(app)
            Gx = feedback(app.Gr*app.Kr*app.Gs,1);
            info = stepinfo(Gx);

            app.risetimeEditField.Value = info.RiseTime;
            app.settlingtimeEditField.Value = info.SettlingTime;
            app.peakEditField.Value = info.Peak;
            app.peaktimeEditField.Value=info.PeakTime;
            app.overshootEditField.Value=info.Overshoot; 

        end
        
        function plotDiagrams(app)
            rlocus(app.rlocus,app.Gr*app.Gs);
            nyquist(app.nyquist,app.Kr*app.Gr*app.Gs);
            bode(app.bode,app.Kr*app.Gr*app.Gs);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
           cla(app.UIAxes);

            m = app.ballmasskgEditField.Value; %mass
            R = app.ballradiusmEditField.Value; %radius
            d = app.leverarmoffsetmEditField.Value; %lever arm offset
            L = app.lengthofthebeammEditField.Value; %beam length
            g = -9.81;
            J=2*m*R*R/5; % moment of inertia for homogeneous filled sphere [kg m * m]
            r0 = app.ballpositionmEditField.Value; %ball position
            rv = app.ReferencevaluemSlider.Value; %reference value

            sigma = app.maxovershootEditField.Value; %max overshoot
            rt = app.maxrisingtimesEditField.Value; %max rising time
            
            calculateParameters(app,m,R,d,L,g,J,sigma,rt);
            
            %display transfer functions and gain on a label
            [numGr,denGr]=tfdata(app.Gr);
            syms s
            Gr_sym=poly2sym(cell2mat(numGr),s)/poly2sym(cell2mat(denGr),s);
            Gr_text=latex(vpa(Gr_sym,4));
            app.GrTrLabel.Text=sprintf("$$%s$$",Gr_text);
            app.GrTrLabel.Interpreter='latex';

            [numGs,denGs]=tfdata(app.Gs);
            syms s
            Gs_sym=poly2sym(cell2mat(numGs),s)/poly2sym(cell2mat(denGs),s);
            Gs_text=latex(vpa(Gs_sym,5));
            app.GsTrLabel.Text=sprintf("$$%s$$",Gs_text);
            app.GsTrLabel.Interpreter='latex';
            
            app.KrvalueLabel.Text=num2str(app.Kr);

            calculateStepInfo(app);
            plotDiagrams(app);       
            startSimulation(app,r0,rv)

        end

        % Value changed function: lengthofthebeammEditField
        function lengthofthebeammEditFieldValueChanged(app, event)
            L = app.lengthofthebeammEditField.Value;

            %setting limits of the reference value (slider) based on the length of
            %the beam
            app.ReferencevaluemSlider.Limits = [-L/2 L/2];
            app.ReferencevalueEditField.Limits = [-L/2 L/2];

        end

        % Value changing function: ReferencevaluemSlider
        function ReferencevaluemSliderValueChanging(app, event)
            changingValue = event.Value;
            %updating simulink model parameters during simulation
            app.ReferencevalueEditField.Value = changingValue;
            set_param('ballAndBeamModel/Step', 'After', num2str(changingValue));

        end

        % Value changed function: ReferencevalueEditField
        function ReferencevalueEditFieldValueChanged(app, event)
            value = app.ReferencevalueEditField.Value;
            
            %setting ref value in the numeric field (insted of slider)
            %checking that the set value is between limits.

            if value < app.ReferencevaluemSlider.Limits(1)
                    value = app.ReferencevaluemSlider.Limits(1);
                    app.ReferencevalueEditField.Value = value;
            end
            if value > app.ReferencevaluemSlider.Limits(2)
                    value = app.ReferencevaluemSlider.Limits(2);
                    app.ReferencevalueEditField.Value = value;
            end

            %update slider and parameter in simulink model
            app.ReferencevaluemSlider.Value = value;
            set_param('ballAndBeamModel/Step', 'After', num2str(value));

        end

        % Button pushed function: StopsimulationButton
        function StopsimulationButtonPushed(app, event)
            set_param('ballAndBeamModel', 'SimulationCommand', 'stop')

             %get structure position from simulink, from workspace
             position=evalin('base','position');
             ref=evalin('base','ref');

             %plot the result of simulation
             plot(app.UIAxes,position.time,position.signals.values);
            
             plot(app.UIAxes,ref.time,ref.signals.values);
                   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.ParametersPanel = uipanel(app.SimulationTab);
            app.ParametersPanel.Title = 'Parameters';
            app.ballmasskgEditFieldLabel = uilabel(app.ParametersPanel);
            app.ballmasskgEditFieldLabel.Text = 'ball mass(kg)';
            app.ballmasskgEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ballmasskgEditField.Limits = [0 Inf];
            app.ballmasskgEditField.Value = 0.11;
            app.ballradiusmEditFieldLabel = uilabel(app.ParametersPanel);
            app.ballradiusmEditFieldLabel.Text = 'ball radius (m)';
            app.ballradiusmEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ballradiusmEditField.Limits = [0 Inf];
            app.ballradiusmEditField.Value = 0.015;
            app.leverarmoffsetmEditFieldLabel = uilabel(app.ParametersPanel);
            app.leverarmoffsetmEditFieldLabel.Text = 'lever arm offset (m)';
            app.leverarmoffsetmEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.leverarmoffsetmEditField.Limits = [0 Inf];
            app.leverarmoffsetmEditField.Value = 0.03;
            app.lengthofthebeammEditFieldLabel = uilabel(app.ParametersPanel);
            app.lengthofthebeammEditFieldLabel.Text = 'length of the beam (m)';
            app.lengthofthebeammEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.lengthofthebeammEditField.Limits = [0 Inf];
            app.lengthofthebeammEditField.ValueChangedFcn = createCallbackFcn(app, @lengthofthebeammEditFieldValueChanged, true);
            app.lengthofthebeammEditField.Value = 1;
            app.ballpositionmEditFieldLabel = uilabel(app.ParametersPanel);
            app.ballpositionmEditFieldLabel.Text = 'ball position (m)';
            app.ballpositionmEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ReferencevaluemSliderLabel = uilabel(app.ParametersPanel);
            app.ReferencevaluemSliderLabel.Text = 'Reference value (m)';
            app.ReferencevaluemSlider = uislider(app.ParametersPanel);
            app.ReferencevaluemSlider.Limits = [-0.5 0.5];
            app.ReferencevaluemSlider.ValueChangingFcn = createCallbackFcn(app, @ReferencevaluemSliderValueChanging, true);
            app.ReferencevalueEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ReferencevalueEditField.ValueChangedFcn = createCallbackFcn(app, @ReferencevalueEditFieldValueChanged, true);
            app.stepresponsecharacteristicsPanel = uipanel(app.SimulationTab);
            app.stepresponsecharacteristicsPanel.Title = ' step-response characteristics';
            app.maxovershootLabel = uilabel(app.stepresponsecharacteristicsPanel);
            app.maxovershootLabel.Text = 'max overshoot [%]';
            app.maxovershootEditField = uieditfield(app.stepresponsecharacteristicsPanel, 'numeric');
            app.maxovershootEditField.Value = 5;
            app.maxrisingtimesEditFieldLabel = uilabel(app.stepresponsecharacteristicsPanel);
            app.maxrisingtimesEditFieldLabel.Text = 'max rising time (s)';
            app.maxrisingtimesEditField = uieditfield(app.stepresponsecharacteristicsPanel, 'numeric');
            app.maxrisingtimesEditField.Value = 1;
            app.SimulateButton = uibutton(app.SimulationTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ResultsPanel_2 = uipanel(app.SimulationTab);
            app.ResultsPanel_2.Title = 'Results';
            app.UIAxes = uiaxes(app.ResultsPanel_2);
            title(app.UIAxes, 'Step response')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'Position  r [m]')
            zlabel(app.UIAxes, 'Z')
            app.BallandbeamsystemLabel = uilabel(app.SimulationTab);
            app.BallandbeamsystemLabel.Text = 'Ball and beam system';
            app.SimulationofaballpostioncontrolLabel = uilabel(app.SimulationTab);
            app.SimulationofaballpostioncontrolLabel.Text = 'Simulation of a ball postion control';
            app.StopsimulationButton = uibutton(app.SimulationTab, 'push');
            app.StopsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopsimulationButtonPushed, true);
            app.StopsimulationButton.Text = 'Stop simulation';
            app.Panel = uipanel(app.SimulationTab);
            app.GsLabel = uilabel(app.Panel);
            app.GsLabel.Text = 'Gs =';
            app.GrLabel = uilabel(app.Panel);
            app.GrLabel.Text = 'Gr =';
            app.KrLabel = uilabel(app.Panel);
            app.KrLabel.Text = 'Kr =';
            app.GsTrLabel = uilabel(app.Panel);
            app.GsTrLabel.Text = '';
            app.GrTrLabel = uilabel(app.Panel);
            app.GrTrLabel.Text = '';
            app.KrvalueLabel = uilabel(app.Panel);
            app.KrvalueLabel.Text = '';
            app.StepinformationPanel = uipanel(app.SimulationTab);
            app.StepinformationPanel.Title = 'Step information';
            app.risetimeEditFieldLabel = uilabel(app.StepinformationPanel);
            app.risetimeEditFieldLabel.Text = 'rise time';
            app.risetimeEditField = uieditfield(app.StepinformationPanel, 'numeric');
            app.settlingtimeEditFieldLabel = uilabel(app.StepinformationPanel);
            app.settlingtimeEditFieldLabel.Text = 'settling time';
            app.settlingtimeEditField = uieditfield(app.StepinformationPanel, 'numeric');
            app.peakEditFieldLabel = uilabel(app.StepinformationPanel);
            app.peakEditFieldLabel.Text = 'peak';
            app.peakEditField = uieditfield(app.StepinformationPanel, 'numeric');
            app.peaktimeEditFieldLabel = uilabel(app.StepinformationPanel);
            app.peaktimeEditFieldLabel.Text = 'peak time';
            app.peaktimeEditField = uieditfield(app.StepinformationPanel, 'numeric');
            app.overshootLabel = uilabel(app.StepinformationPanel);
            app.overshootLabel.Text = 'overshoot [%]';
            app.overshootEditField = uieditfield(app.StepinformationPanel, 'numeric');
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.BallandbeamsystemLabel_2 = uilabel(app.AnalysisTab);
            app.BallandbeamsystemLabel_2.Text = 'Ball and beam system';
            app.SimulationofaballpostioncontrolLabel_2 = uilabel(app.AnalysisTab);
            app.SimulationofaballpostioncontrolLabel_2.Text = 'Simulation of a ball postion control';
            app.BodePanel = uipanel(app.AnalysisTab);
            app.BodePanel.Title = 'Bode ';
            app.bode = uiaxes(app.BodePanel);
            title(app.bode, 'Title')
            xlabel(app.bode, 'X')
            ylabel(app.bode, 'Y')
            zlabel(app.bode, 'Z')
            app.NyquistPanel = uipanel(app.AnalysisTab);
            app.NyquistPanel.Title = 'Nyquist';
            app.nyquist = uiaxes(app.NyquistPanel);
            title(app.nyquist, 'Title')
            xlabel(app.nyquist, 'X')
            ylabel(app.nyquist, 'Y')
            zlabel(app.nyquist, 'Z')
            app.rlocusPanel = uipanel(app.AnalysisTab);
            app.rlocusPanel.Title = 'rlocus';
            app.rlocus = uiaxes(app.rlocusPanel);
            title(app.rlocus, 'Title')
            xlabel(app.rlocus, 'X')
            ylabel(app.rlocus, 'Y')
            zlabel(app.rlocus, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ballAndBeamapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end